classdef BodePlotsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        s_LSlider       matlab.ui.control.Slider
        s_LSliderLabel  matlab.ui.control.Label
        s_RSlider       matlab.ui.control.Slider
        s_RSliderLabel  matlab.ui.control.Label
        G22UIAxes       matlab.ui.control.UIAxes
        G21UIAxes       matlab.ui.control.UIAxes
        G12UIAxes       matlab.ui.control.UIAxes
        G11UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Results % Store results data
    end
    
    methods (Access = private)
        
        function updatePlots(app)
            % Get slider values
            s_L = app.s_LSlider.Value;
            s_R = app.s_RSlider.Value;
            
            % Find nearest indices
            slipRange = 0:0.05:0.4;
            [~, i_R] = min(abs(slipRange - s_R));
            [~, i_L] = min(abs(slipRange - s_L));
            
            % Get transfer functions
            G11 = app.Results{i_R,i_L}.G11;
            G12 = app.Results{i_R,i_L}.G12;
            G21 = app.Results{i_R,i_L}.G21;
            G22 = app.Results{i_R,i_L}.G22;
            
            % Plot Bode diagrams
            bode(G11, app.G11UIAxes);
            bode(G12, app.G12UIAxes);
            bode(G21, app.G21UIAxes);
            bode(G22, app.G22UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SliderValueChanged(app, results)
            updatePlots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.G11UIAxes = uiaxes(app.UIFigure);
            title(app.G11UIAxes, 'G_{11}')
            xlabel(app.G11UIAxes, 'X')
            ylabel(app.G11UIAxes, 'Y')
            zlabel(app.G11UIAxes, 'Z')
            app.G12UIAxes = uiaxes(app.UIFigure);
            title(app.G12UIAxes, 'G_{12}')
            xlabel(app.G12UIAxes, 'X')
            ylabel(app.G12UIAxes, 'Y')
            zlabel(app.G12UIAxes, 'Z')
            app.G21UIAxes = uiaxes(app.UIFigure);
            title(app.G21UIAxes, 'G_{21}')
            xlabel(app.G21UIAxes, 'X')
            ylabel(app.G21UIAxes, 'Y')
            zlabel(app.G21UIAxes, 'Z')
            app.G22UIAxes = uiaxes(app.UIFigure);
            title(app.G22UIAxes, 'G_{22}')
            xlabel(app.G22UIAxes, 'X')
            ylabel(app.G22UIAxes, 'Y')
            zlabel(app.G22UIAxes, 'Z')
            app.s_RSliderLabel = uilabel(app.UIFigure);
            app.s_RSliderLabel.Text = 's_R';
            app.s_RSlider = uislider(app.UIFigure);
            app.s_RSlider.Limits = [0 0.4];
            app.s_RSlider.MajorTicks = [0 0.1 0.2 0.3 0.4];
            app.s_LSliderLabel = uilabel(app.UIFigure);
            app.s_LSliderLabel.Text = 's_L';
            app.s_LSlider = uislider(app.UIFigure);
            app.s_LSlider.Limits = [0 0.4];
            app.s_LSlider.MajorTicks = [0 0.1 0.2 0.3 0.4];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BodePlotsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)SliderValueChanged(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end